classdef ad_001 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MASSSPRINGSYSTEMLabel  matlab.ui.control.Label
        fDSlider               matlab.ui.control.Slider
        fDSliderLabel          matlab.ui.control.Label
        ASlider                matlab.ui.control.Slider
        ASliderLabel           matlab.ui.control.Label
        bSlider                matlab.ui.control.Slider
        bSliderLabel           matlab.ui.control.Label
        kSlider                matlab.ui.control.Slider
        kSliderLabel           matlab.ui.control.Label
        mSlider                matlab.ui.control.Slider
        mSliderLabel           matlab.ui.control.Label
        UIAxes7                matlab.ui.control.UIAxes
        UIAxes6                matlab.ui.control.UIAxes
        UIAxes5                matlab.ui.control.UIAxes
        UIAxes4                matlab.ui.control.UIAxes
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        m
        k
        b
        A
        fD
        TD
        C
  
       t
       x
       v
       a
       f0
       
       E
       K
       U
       psd
       f
       T0
       fPeaks
    end
    
  % ====================================================================
    methods (Access = private)
  % =====================================================================

  % == ==================================================================     
     function execute(app)
        %    cla(app.UIAxes,'reset')
        %    hold(app.UIAxes,'off')
     % Time interval & initial conditions for ode45   
       % tSpan = [0 10]
       tMin = 0; tMax = 10;
       tSpan = linspace(tMin,tMax,999);
         u0 = [1;0];
     % Input parameters
         m = app.mSlider.Value;
         k = app.kSlider.Value;
         b = app.bSlider.Value;
         A = app.ASlider.Value;
         fD = app.fDSlider.Value;

      %  m = 10
      %  k = 394.7842

     % Constants for ode45      
         C(1) = -k/m ;
         C(2) = -b/m;
         C(3) =  A/m;
         C(4) =  fD; 

         if A > 0 && fD > 0
             u0 = [0;0];
         end
    % ode45
        % options = odeset('RelTol',RelTol); 
           [t, SOL] = ode45(@(t,u) FNode(app,t,u,C), tSpan, u0);

     % CALCULATIONS  ==================================================
        % Displacement x / velocity v / acceleration a    
            x = SOL(:,1);
            v = SOL(:,2);
            a = -(k/m).*x - (b/m).*v  + (A/m).*sin(2*pi*fD*t);
        % Natural frequencies and period
            w0 = sqrt(k/m);
            f0 = w0/(2*pi);
            T0 = 1/f0;
            TD = 1/fD;
        % Total energy E / kinetic K / potential U   
            K = (0.5*m).*v.^2;
            U = (0.5*k).*x.^2;
            E = K + U;

   % FOURIER TRANSFER
            fMin = 0; fMax = 3; nF = 999;
            H = zeros(nF,1);
            f = linspace(fMin,fMax,nF);
            for c = 1:nF
              g = x.*exp(1i*2*pi*f(c)*t);
              H(c) = Simpson1d(app,g',tMin,tMax);
            end
            psd = conj(H).*H;
            psd = psd/max(psd);
            [pks loc] = findpeaks(psd,'MinPeakHeight', 0.1);
            app.fPeaks = f(loc);

   % MAKE GLOBAL VARIABLES  =======================================
            app.m = m;
            app.k = k;
            app.b = b;
            app.A = A;
            appfD = fD;
            app.t = t;
            app.x = x;
            app.v = v;
            app.a = a;
            app.E = E;
            app.K = K;
            app.U = U;
            app.psd = psd;
            app.f = f;
            app.f0 = f0;
            app.T0 = T0;
            app.TD = TD;
            

       %     app.f0EditField.Value = max(f);

            plot1(app)
        end
%  ======================================================================

%  ======================================================================     
    function plot1(app)
      % (1)  Displacement  x/t
         xP = app.t;
         yP = app.x;
            plot(app.UIAxes,xP,yP,'b','LineWidth',2) 
         app.UIAxes.XLim = [0 10];
         app.UIAxes.YLim = [-2 2];
         app.UIAxes.XTick = 0:2:10;
         app.UIAxes.YTick = -2:1:2;
         app.UIAxes.Box  = 'on';
         app.UIAxes.XGrid = 'on';
         app.UIAxes.YGrid = 'on';
 
     % (2) Velocity v/t 
        xP = app.t;
        yP = app.v;
           plot(app.UIAxes2,xP,yP,'b','LineWidth',2)  
        app.UIAxes2.XTick = 0:2:10;
        app.UIAxes2.YLim = [-5 5];
        app.UIAxes2.YTick = [-5,-2.5,0,2.5,5];
        app.UIAxes2.Box  = 'on';
        app.UIAxes2.XGrid = 'on';
        app.UIAxes2.YGrid = 'on';

     % (4) Acceleration  a/t    
        xP = app.t;
        yP = app.a;
           plot(app.UIAxes4,xP,yP,'b','LineWidth',2)  
        app.UIAxes4.XTick = 0:2:10;
        app.UIAxes4.YLim = [-20 20];
        app.UIAxes4.YTick = -20:10:20;
        app.UIAxes4.Box  = 'on';
        app.UIAxes4.XGrid = 'on';
        app.UIAxes4.YGrid = 'on';

     % (3) Phase plot  v/x    
        xP = app.x;
        yP = app.v;
           plot(app.UIAxes3,xP,yP,'b','LineWidth',2) 

        app.UIAxes3.XTick = -2:1:2;
        app.UIAxes3.XLim = [-2 2];
        app.UIAxes3.YTick = -5:2.5:5;
        app.UIAxes3.YLim = [-5 5];
        app.UIAxes3.Box  = 'on';
        app.UIAxes3.XGrid = 'on';
        app.UIAxes3.YGrid = 'on';

     % (5) F.T.   psd / f
         xP = app.f;
         yP = app.psd;
           plot(app.UIAxes5,xP,yP,'b','LineWidth',2)  
        app.UIAxes5.YTick = 0:0.5:1;
        app.UIAxes5.XTick = 0:0.5:3;
        app.UIAxes5.XLim = [0 3];
        app.UIAxes5.Box  = 'on';
        app.UIAxes5.XGrid = 'on';
        app.UIAxes5.YGrid = 'on';


    % (6) Energy E K U / t  
        hold(app.UIAxes6,'off')
        xP = app.t;
        yP = app.K;
           plot(app.UIAxes6,xP,yP,'b','LineWidth',2)
          hold(app.UIAxes6,'on')
         yP = app.U;
            plot(app.UIAxes6,xP,yP,'r','LineWidth',2)
         yP = app.E;
            plot(app.UIAxes6,xP,yP,'k','LineWidth',2)   
        app.UIAxes6.XTick = 0:2:10;
        app.UIAxes6.Box  = 'on';
        app.UIAxes6.XGrid = 'on';
        app.UIAxes6.YGrid = 'on';

   % (7) Output
         cla(app.UIAxes7,'reset')
         FS = 14;
         hx = 2; hy = 92; dhy = 15; 
         txt = sprintf('m = %2.1f kg \n', app.m);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS);

         hx = 49; 
         txt = sprintf('k = %2.1f N.m^{-1} \n', app.k);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS);

         hx = 2; hy = hy - dhy;
         txt = sprintf('b = %2.1f kg.s^{-1} \n', app.b);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS);

         hx = 49; 
         txt = sprintf('A = %2.1f N \n', app.A);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS);

         hx = 2; hy = hy - dhy;
         txt = sprintf('T_0 = %2.2f s \n', app.T0);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS);
         
         hx = 49;
         txt = sprintf('f_0 = %2.2f Hz \n', app.f0);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS);
 
         hx = 2; hy = hy - dhy;
         txt = sprintf('T_D = %2.2f s \n', app.TD);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS);

         hx = 49; 
         txt = sprintf('f_D = %2.2f Hz \n', app.fD);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS);
        
         hx = 2; hy = hy - dhy;
         txt = sprintf('f_{peaks} = %2.2f Hz \n', app.fPeaks);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS);
        

         app.UIAxes7.XLim = [0 100];
         app.UIAxes7.YLim = [0 100];
         axis(app.UIAxes7,'off')
    end % plot1(app)

%  ======================================================================     
        function du = FNode(app,t,u,C)
             y = u(1);
             yDot = u(2);
             du = zeros(2,1);
      % ODE: First derivative dy/dt
            du(1) = yDot;
      % ODE: Second derivative  d2y/dt2
            du(2) = C(1)*y + C(2)*yDot + C(3)*sin(2*pi*C(4)*t);
        end
%  =====================================================================        
             
%  =====================================================================      
 function integral = Simpson1d(~,f,a,b)
              % [1D] integration - Simpson's 1/3 rule
  %      f function    a = lower bound    b = upper bound
  %      Must have odd number of data points
  %      Simpson's coefficients   1 4 2 4 ... 2 4 1

    numS = length(f);               % number of data points
  if mod(numS,2) == 1
      sc = 2*ones(numS,1);
      sc(2:2:numS-1) = 4;
      sc(1) = 1; sc(numS) = 1;
      h = (b-a)/(numS-1);
      integral = (h/3) * f * sc;
  else
      integral = 'Length of function must be an ODD number'; 
  end
end 

 % ====================================================================       
    end   % END METHODS
 % =====================================================================   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            execute(app)
        end

        % Value changed function: mSlider
        function mSliderValueChanged(app, event)
            app.m = app.mSlider.Value;
            execute(app)
        end

        % Value changed function: kSlider
        function kSliderValueChanged(app, event)
            app.k = app.kSlider.Value;
            execute(app)
        end

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
            app.b = app.bSlider.Value;
            execute(app)
        end

        % Value changed function: ASlider
        function ASliderValueChanged(app, event)
            app.A = app.ASlider.Value;
            execute(app)
        end

        % Value changed function: fDSlider
        function fDSliderValueChanged(app, event)
            app.fD = app.fDSlider.Value;
            execute(app)
        end

        % Value changing function: kSlider
        function kSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 't  [ s ]')
            ylabel(app.UIAxes, 'x  [ m ]')
            app.UIAxes.XTick = [-2 -1 0 1 2];
            app.UIAxes.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 't  [s]')
            ylabel(app.UIAxes2, 'v  [ m.s^{-1} ]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [0 2 4 6 8 10];
            app.UIAxes2.YTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxes2.YTickLabel = {'-5'; '-2.5'; '0'; '2.5'; '5'};
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'x  [ m]')
            ylabel(app.UIAxes3, 'v  [ m.s^{1} ]')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes4, 't  [s]')
            ylabel(app.UIAxes4, 'a  [m/s^2]')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes5, 'f  [ Hz ]')
            ylabel(app.UIAxes5, 'p.s.d.')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.YTick = 1;
            app.UIAxes6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes6, 't  [ s ]')
            ylabel(app.UIAxes6, 'E U K  [ J ]')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.mSliderLabel = uilabel(app.UIFigure);
            app.mSliderLabel.Text = 'm';
            app.mSlider = uislider(app.UIFigure);
            app.mSlider.Limits = [1 10];
            app.mSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.mSlider.ValueChangedFcn = createCallbackFcn(app, @mSliderValueChanged, true);
            app.mSlider.MinorTicks = [5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.mSlider.Value = 5;
            app.kSliderLabel = uilabel(app.UIFigure);
            app.kSliderLabel.Text = 'k';
            app.kSlider = uislider(app.UIFigure);
            app.kSlider.Limits = [0 200];
            app.kSlider.MajorTicks = [0 50 100 150 200];
            app.kSlider.MajorTickLabels = {'0', '50', '100', '150', '200'};
            app.kSlider.ValueChangedFcn = createCallbackFcn(app, @kSliderValueChanged, true);
            app.kSlider.ValueChangingFcn = createCallbackFcn(app, @kSliderValueChanging, true);
            app.kSlider.MinorTicks = [];
            app.kSlider.Value = 100;
            app.bSliderLabel = uilabel(app.UIFigure);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.UIFigure);
            app.bSlider.Limits = [0 5];
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.ASliderLabel = uilabel(app.UIFigure);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.UIFigure);
            app.ASlider.Limits = [0 10];
            app.ASlider.ValueChangedFcn = createCallbackFcn(app, @ASliderValueChanged, true);
            app.fDSliderLabel = uilabel(app.UIFigure);
            app.fDSliderLabel.Text = 'fD';
            app.fDSlider = uislider(app.UIFigure);
            app.fDSlider.Limits = [0 3];
            app.fDSlider.ValueChangedFcn = createCallbackFcn(app, @fDSliderValueChanged, true);
            app.MASSSPRINGSYSTEMLabel = uilabel(app.UIFigure);
            app.MASSSPRINGSYSTEMLabel.Text = 'MASS / SPRING SYSTEM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ad_001

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end