classdef ad_002B < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SINUSOIDALFUNCTIONSLabel  matlab.ui.control.Label
        phi2Slider                matlab.ui.control.Slider
        phi2SliderLabel           matlab.ui.control.Label
        T2Slider                  matlab.ui.control.Slider
        T2SliderLabel             matlab.ui.control.Label
        A2Slider                  matlab.ui.control.Slider
        A2SliderLabel             matlab.ui.control.Label
        UIAxes7                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       y1
       y2
       A1
       A2
       T1
       T2
       f1
       f2
       w1
       w2
       phi1
       phi2
       t

    end
    
  % ====================================================================
    methods (Access = private)
  % =====================================================================

  % == ==================================================================     
     function execute(app)
        %    cla(app.UIAxes,'reset')
        %    hold(app.UIAxes,'off')
     % Time interval & initial conditions for ode45   
       % tSpan = [0 10]
       tMin = 0; tMax = 100;
       app.t = linspace(tMin,tMax,999);
       
     % Input parameters
         app.A2   = app.A2Slider.Value;
         app.T2   = app.T2Slider.Value;
         app.phi2 = app.phi2Slider.Value;
         app.A1 = 6;
         app.T1 = 20;
         app.phi1 = 0;

     % CALCULATIONS  ==================================================
     % Frequencies
         app.f1 = 1/app.T1;
         app.f2= 1/app.T2;
         app.w1 = 2*pi*app.f1;
         app.w2 = 2*pi*app.f2;
     %  Sinusoidal functions    
        app.y1 = app.A1 .* sin(app.w1*app.t + pi*app.phi1);
        app.y2 = app.A2 .* sin(app.w2*app.t + pi*app.phi2);
     
        plot1(app)
        end
%  ======================================================================

%  ======================================================================     
    function plot1(app)
          
         dA = abs(app.A1-app.A2); dP = abs(app.T1-app.T2);

       % (1)  Displacement 
          hold(app.UIAxes,'off')
          xP = app.t;
          yP = app.y1;
             plot(app.UIAxes,xP,yP,'b','LineWidth',2) 
          hold(app.UIAxes,'on') 
          yP = app.y2;
             plot(app.UIAxes,xP,yP,'r','LineWidth',2) 

  
        % app.UIAxes.XLim = [0 100];
        % app.UIAxes.XTick = 0:10:100;
        % app.UIAxes.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
        
         txt = 'y_1 = A_1 sin(2\pi t / T_1)';
            text(app.UIAxes,3,9,txt,'fontsize',16,'Color','b', ...
                'FontWeight','bold');

         txt = 'y_2 = A_2 sin(2\pi t / T_2 + \phi_2)';
            text(app.UIAxes,47,9,txt,'fontsize',16,'Color','r', ...
                'FontWeight','bold');

         app.UIAxes.YLim = [-10 10];
         app.UIAxes.YTick = -10:2:10;
         app.UIAxes.Box  = 'on';
         app.UIAxes.XGrid = 'on';
         app.UIAxes.YGrid = 'on';
     

%      % (4) Polar plot
          hold(app.UIAxes4,'off') 
          R = linspace(0,2*pi,999);
          xP = app.A1.*cos(R);
          yP = app.A1.*sin(R);
            plot(app.UIAxes4,xP,yP,'b','LineWidth',1) 
          hold(app.UIAxes4,'on')   
          xP = [0 app.A1];
          yP = [0 0];
            plot(app.UIAxes4,xP,yP,'b','LineWidth',2)   

          xP = app.A2.*cos(R);
          yP = app.A2.*sin(R);
            plot(app.UIAxes4,xP,yP,'r','LineWidth',1) 

          xP = [0  app.A2*cos(pi*app.phi2)];
          yP = [0 app.A2*sin(pi*app.phi2)];
             plot(app.UIAxes4,xP,yP,'r','LineWidth',2) 

           R = linspace(0,pi*app.phi2,999);
           xP = app.A2.*cos(R);
           yP = app.A2.*sin(R);
            plot(app.UIAxes4,xP,yP,'r','LineWidth',4) 

          
          txt = sprintf('\\phi_2 / \\pi = %2.2f  rad  \n',app.phi2);
          title(app.UIAxes4,txt,'Color','r')
          axis(app.UIAxes4, 'square');
          
          if dA < 0.5 && dP < 0.5
             if app.phi2 < 0;  txt ='LAGS'; end
             if app.phi2 > 0;  txt ='LEADS'; end
             if app.phi2 == 0; txt ='IN PHASE'; end
                text(app.UIAxes4,-9,8.5,txt,'Color','r','FontWeight','bold', ...
                'FontSize',16)  
          end

          app.UIAxes4.XLim  = [-10 10];
          app.UIAxes4.XTick = -10:5:10;
          app.UIAxes4.YLim   = [-10 10];
          app.UIAxes4.YTick = -10:5:10;
          app.UIAxes4.Box  = 'on';
          app.UIAxes4.XGrid = 'on';
          app.UIAxes4.YGrid = 'on';
          app.UIAxes4.FontSize = 16;

%    % (7) Output
          cla(app.UIAxes7,'reset')
          FS = 16;
          hx = 2; hy = 92; dhy = 17; 
          txt = sprintf('A_1 = %2.3f \n', app.A1);
          text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','b');
 
          hx = 49; 
          txt = sprintf('A_2 = %2.3f  \n', app.A2);
          text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','r');
 
          hx = 2; hy = hy - dhy;
          txt = sprintf('T_1 = %2.3f \n', app.T1);
          text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','b');
 
          hx = 49; 
          txt = sprintf('T_2 = %2.3f  \n', app.T2);
          text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','r');

         hx = 2; hy = hy - dhy;
         txt = sprintf('f_1 = %2.3f  \n', app.f1);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','b');
         
         hx = 49;
         txt = sprintf('f_2 = %2.3f \n', app.f2);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','r');
 
         hx = 2; hy = hy - dhy;
         txt = sprintf('\\omega_1 = %2.3f \n', app.w1);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','b');

         hx = 49; 
         txt = sprintf('\\omega_2 = %2.3f \n', app.w2);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','r');

         hx = 2; hy = hy - dhy;
         txt = sprintf('\\phi_1 = %2.3f \n', 0);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','b');

         hx = 49; 
         txt = sprintf('\\phi_2 = %2.3f  \\pi rad \n', app.phi2);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','r');

         
         if dA < 0.5 && dP < 0.5
           hx = 2; hy = hy - dhy;
           dT = app.phi2*app.T2/2;
           txt = sprintf('\\Deltat = %2.3f \n', dT);
         text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','b');
         end

         app.UIAxes7.XLim = [0 100];
         app.UIAxes7.YLim = [0 100];
         axis(app.UIAxes7,'off')

     end % plot1(app)


 % ====================================================================       
    end   % END METHODS
 % =====================================================================   


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            execute(app)
        end

        % Value changed function: A2Slider
        function A2SliderValueChanged(app, event)
            app.A2 = app.A2Slider.Value;
            execute(app)
        end

        % Value changed function: T2Slider
        function T2SliderValueChanged(app, event)
            app.T2 = app.T2Slider.Value;
            execute(app)
        end

        % Value changed function: phi2Slider
        function phi2SliderValueChanged(app, event)
            app.phi2 = app.phi2Slider.Value;
            execute(app)
        end

        % Callback function
        function ASliderValueChanged(app, event)
           
        end

        % Callback function
        function fDSliderValueChanged(app, event)
            
        end

        % Value changing function: T2Slider
        function T2SliderValueChanging(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'y')
            app.UIAxes.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.UIAxes4 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes7, 'Z')
            app.A2SliderLabel = uilabel(app.UIFigure);
            app.A2SliderLabel.Text = 'A2';
            app.A2Slider = uislider(app.UIFigure);
            app.A2Slider.Limits = [0 10];
            app.A2Slider.MajorTicks = [0 2 4 6 8 10];
            app.A2Slider.MajorTickLabels = {'0', '2', '4', '6', '8', '10'};
            app.A2Slider.ValueChangedFcn = createCallbackFcn(app, @A2SliderValueChanged, true);
            app.A2Slider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.A2Slider.Value = 8;
            app.T2SliderLabel = uilabel(app.UIFigure);
            app.T2SliderLabel.Text = 'T2';
            app.T2Slider = uislider(app.UIFigure);
            app.T2Slider.MajorTicks = [0 20 40 60 80 100];
            app.T2Slider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.T2Slider.ValueChangedFcn = createCallbackFcn(app, @T2SliderValueChanged, true);
            app.T2Slider.ValueChangingFcn = createCallbackFcn(app, @T2SliderValueChanging, true);
            app.T2Slider.MinorTicks = [0 10 20 30 40 50 70 90];
            app.T2Slider.Value = 80;
            app.phi2SliderLabel = uilabel(app.UIFigure);
            app.phi2SliderLabel.Text = 'phi2';
            app.phi2Slider = uislider(app.UIFigure);
            app.phi2Slider.Limits = [-1 1];
            app.phi2Slider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.phi2Slider.MajorTickLabels = {'-1', '-0.5', '0', '0.5', '1'};
            app.phi2Slider.ValueChangedFcn = createCallbackFcn(app, @phi2SliderValueChanged, true);
            app.phi2Slider.MinorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.phi2Slider.Value = -0.5;
            app.SINUSOIDALFUNCTIONSLabel = uilabel(app.UIFigure);
            app.SINUSOIDALFUNCTIONSLabel.Text = 'SINUSOIDAL FUNCTIONS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ad_002B

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end