classdef ad_002C < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        aSlider                   matlab.ui.control.Slider
        aSliderLabel              matlab.ui.control.Label
        ASlider                   matlab.ui.control.Slider
        ASliderLabel              matlab.ui.control.Label
        SINUSOIDALFUNCTIONSLabel  matlab.ui.control.Label
        phiSlider                 matlab.ui.control.Slider
        phiSliderLabel            matlab.ui.control.Label
        bSlider                   matlab.ui.control.Slider
        bSliderLabel              matlab.ui.control.Label
        UIAxes7                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
      t
      y
      yS
      yC
      ySC
      A
      phi
      a
      b
      w
      f
      T
    end
    
  % ====================================================================
    methods (Access = private)
  % =====================================================================

  % == ==================================================================     
     function execute(app)
        %    cla(app.UIAxes,'reset')
        %    hold(app.UIAxes,'off')
     % Time interval & initial conditions for ode45   
       % tSpan = [0 10]
       tMin = 0; tMax = 100;
       app.t = linspace(tMin,tMax,999);
       
     % Input parameters
         app.A     = app.ASlider.Value;
         app.phi   = app.phiSlider.Value;
         app.a     = app.aSlider.Value;
         app.b     = app.bSlider.Value;
        

     % CALCULATIONS  ==================================================
     % Period and Frequencies
         app.T = 40;
         app.f = 1/app.T;
         app.w = 2*pi*app.f;
         
     %  Sinusoidal functions    
        app.y  = app.A .* sin(app.w*app.t + pi*app.phi);
        app.yS = app.a .* sin(app.w*app.t);
        app.yC = app.b .* cos(app.w*app.t);
        app.ySC = app.yS + app.yC;

      plot1(app)
        end
%  ======================================================================

%  ======================================================================     
    function plot1(app)
       % (1)  Displacement 
          hold(app.UIAxes,'off')
          xP = app.t;
          yP = app.y;
             plot(app.UIAxes,xP,yP,'b','LineWidth',2) 
          hold(app.UIAxes,'on') 
          yP = app.yS;
             plot(app.UIAxes,xP,yP,'r','LineWidth',1.2) 
          yP = app.yC;
             plot(app.UIAxes,xP,yP,'m','LineWidth',1.2)
          yP = app.ySC;
             plot(app.UIAxes,xP,yP,'k','LineWidth',2) 
  
%         app.UIAxes.XLim = [0 100];
%         app.UIAxes.XTick = 0:10:100;
%         app.UIAxes.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
%         
%          txt = 'y_1 = A_1 sin(2\pi t / T_1)';
%             text(app.UIAxes,3,9,txt,'fontsize',16,'Color','b', ...
%                 'FontWeight','bold');
% 
%          txt = 'y_2 = A_2 sin(2\pi t / T_2 - \phi_2)';
%             text(app.UIAxes,47,9,txt,'fontsize',16,'Color','r', ...
%                 'FontWeight','bold');

         app.UIAxes.YLim = [-10 10];
         app.UIAxes.YTick = -10:2:10;
         app.UIAxes.Box  = 'on';
         app.UIAxes.XGrid = 'on';
         app.UIAxes.YGrid = 'on';
     

%      % (4) Polar plot
          hold(app.UIAxes4,'off') 
          R = linspace(0,2*pi,999);
          xP = -app.A.*cos(R);
          yP = app.A.*sin(R);
            plot(app.UIAxes4,xP,yP,'b','LineWidth',1) 
          hold(app.UIAxes4,'on')   
          xP = [0 app.A.*cos(pi*app.phi)];
          yP = [0 app.A.*sin(pi*app.phi)];
            plot(app.UIAxes4,xP,yP,'b','LineWidth',2)   

          ASC = sqrt(app.a^2 +app.b^2);  
          xP = ASC.*cos(R);
          yP = ASC.*sin(R);
            plot(app.UIAxes4,xP,yP,'k','LineWidth',1) 

           RSC = atan2(app.b,app.a);  
           xP = [0  ASC*cos(RSC)];
           yP = [0  ASC*sin(RSC)];
             plot(app.UIAxes4,xP,yP,'k','LineWidth',2)

           xP = [0 app.a]; 
           yP = [0 0];
              plot(app.UIAxes4,xP,yP,'r','LineWidth',3) 

           xP = [0 0]; 
           yP = [0 app.b];
              plot(app.UIAxes4,xP,yP,'m','LineWidth',3)    
           
           xP = [app.a app.a]; 
           yP = [0 app.b];
              plot(app.UIAxes4,xP,yP,'k','LineWidth',0.5)   

           xP = [0 app.a]; 
           yP = [app.b app.b];
              plot(app.UIAxes4,xP,yP,'k','LineWidth',0.5)   
  
%           
%           txt = sprintf('-\\phi_2 / \\pi = %2.2f  rad  \n',-app.phi2);
%           title(app.UIAxes4,txt,'Color','r')
          axis(app.UIAxes4, 'square');
              
          app.UIAxes4.XLim  = [-10 10];
          app.UIAxes4.XTick = -10:5:10;
          app.UIAxes4.YLim   = [-10 10];
          app.UIAxes4.YTick = -10:5:10;
          app.UIAxes4.Box  = 'on';
          app.UIAxes4.XGrid = 'on';
          app.UIAxes4.YGrid = 'on';
          app.UIAxes4.FontSize = 16;

    % (7) Output
          cla(app.UIAxes7,'reset')
          FS = 16;
          hx = 2; hy = 92; dhy = 17; 
          txt = 'y = A sin(\omegat + \phi)';
          text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','b');
 
          hx = 2; hy = hy - dhy;
          txt = sprintf('A = %2.3f  \n', app.A);
          text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','b');
 
          hx = 49;
          txt = sprintf('\\phi = %2.3f  \n', app.phi);
          text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','b');

          hx = 2; hy = 65;
          txt = 'y_S = a sin(\omegat)';
          text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','r');
   
          hx = 49;  hy = 62;
          txt = sprintf('a = %2.3f  \n', app.a);
          text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','r');

          hx = 2; hy = 55;
          txt = 'y_C = b cos(\omegat)';
          text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','m');
   
          hx = 49; hy = 52;
          txt = sprintf('b = %2.3f  \n', app.b);
          text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','m');

          hx = 2; hy = 40;
          txt = 'y_{SC} = a sin(\omegat) + b cos(\omegat)';
          text(app.UIAxes7,hx,hy,txt,'fontsize',FS,'Color','k');
      
        app.UIAxes7.XLim = [0 100];
         app.UIAxes7.YLim = [0 100];
          axis(app.UIAxes7,'off')

     end % plot1(app)


 % ====================================================================       
    end   % END METHODS
 % =====================================================================   


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            execute(app)
        end

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
            app.b = app.bSlider.Value;
            execute(app)
        end

        % Value changed function: phiSlider
        function phiSliderValueChanged(app, event)
            app.phi = app.phiSlider.Value;
            execute(app)
        end

        % Value changed function: aSlider
        function aSliderValueChanged(app, event)
           app.a = app.aSlider.Value;
            execute(app)
        end

        % Value changed function: ASlider
        function ASliderValueChanged(app, event)
            app.A = app.ASlider.Value;
            execute(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'y')
            app.UIAxes.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.UIAxes4 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes7, 'Z')
            app.bSliderLabel = uilabel(app.UIFigure);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.UIFigure);
            app.bSlider.Limits = [-8 8];
            app.bSlider.MajorTicks = [-8 -6 -4 -2 0 2 4 6 8];
            app.bSlider.MajorTickLabels = {'-8', '-6', '-4', '-2', '0', '2', '4', '6', '8'};
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.bSlider.MinorTicks = [-8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8];
            app.bSlider.Value = -6;
            app.phiSliderLabel = uilabel(app.UIFigure);
            app.phiSliderLabel.Text = 'phi';
            app.phiSlider = uislider(app.UIFigure);
            app.phiSlider.Limits = [-1 1];
            app.phiSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.phiSlider.MajorTickLabels = {'-1', '-0.5', '0', '0.5', '1'};
            app.phiSlider.ValueChangedFcn = createCallbackFcn(app, @phiSliderValueChanged, true);
            app.phiSlider.MinorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.phiSlider.Value = 0.25;
            app.SINUSOIDALFUNCTIONSLabel = uilabel(app.UIFigure);
            app.SINUSOIDALFUNCTIONSLabel.Text = 'SINUSOIDAL FUNCTIONS';
            app.ASliderLabel = uilabel(app.UIFigure);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.UIFigure);
            app.ASlider.Limits = [0 10];
            app.ASlider.ValueChangedFcn = createCallbackFcn(app, @ASliderValueChanged, true);
            app.ASlider.MinorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10];
            app.ASlider.Value = 6;
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.Limits = [-8 8];
            app.aSlider.MajorTicks = [-8 -6 -4 -2 0 2 4 6 8];
            app.aSlider.MajorTickLabels = {'-8', '-6', '-4', '-2', '0', '2', '4', '6', '8'};
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanged, true);
            app.aSlider.MinorTicks = [-8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8];
            app.aSlider.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ad_002C

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end