classdef app005 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Button                 matlab.ui.control.Button
        STARTButton            matlab.ui.control.Button
        PhaseSlider            matlab.ui.control.Slider
        PhaseSliderLabel       matlab.ui.control.Label
        WavelengthSlider       matlab.ui.control.Slider
        WavelengthSliderLabel  matlab.ui.control.Label
        AmplitudeSlider        matlab.ui.control.Slider
        AmplitudeSliderLabel   matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: AmplitudeSlider, PhaseSlider, PhaseSlider, 
        % WavelengthSlider, WavelengthSlider
        function AmplitudeSliderValueChanged(app, event)
            %clc
            close all
            A = app.AmplitudeSlider.Value;
            wL = app.WavelengthSlider.Value;
            phi = app.PhaseSlider.Value;
            phi = phi*pi;
            x = linspace(0,100,999);
            y = A.*sin(2*pi*x/wL + phi);
            
            plot(app.UIAxes,x,y,'b','linewidth',2)
            txt = sprintf('A = %4.1f \n' ,A);
            text(app.UIAxes,10,12,txt,'fontsize',16)
            txt = sprintf('\\lambda = %4.1f \n' ,wL);
            text(app.UIAxes,40,12,txt,'fontsize',16)
            txt = sprintf('\\phi / \\pi = %4.1f \n' ,phi);
            text(app.UIAxes,70,12,txt,'fontsize',16)
            
            
        end

        % Callback function
        function PhaseSliderValueChanged(app, event)
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
AmplitudeSliderValueChanged(app, event)
            %            
%A = 5;
%             wL = 50;
%             phi = 0;
%             x = linspace(0,100,999);
%             y = A.*sin(2*pi*x/wL + phi);
%             
%             plot(app.UIAxes,x,y)
%             txt = sprintf('A = %4.1f \n' ,A);
%             text(app.UIAxes,10,8,txt)
%             text(app.UIAxes,10,6,num2str(wL,'%4.1f'))
%             text(app.UIAxes,10,4,num2str(phi,'%4.2f'))
%             
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            close all
            A = app.AmplitudeSlider.Value;
            wL = app.WavelengthSlider.Value;
            phi = app.PhaseSlider.Value;
            phi = phi*pi;
            x = linspace(0,100,999);
            y = A.*sin(2*pi*x/wL + phi);
            
            plot(app.UIAxes,x,y,'b','linewidth',2)
            txt = sprintf('A = %4.1f \n' ,A);
            text(app.UIAxes,10,12,txt,'fontsize',16)
            txt = sprintf('\\lambda = %4.1f \n' ,wL);
            text(app.UIAxes,40,12,txt,'fontsize',16)
            txt = sprintf('\\phi / \\pi = %4.1f \n' ,phi);
            text(app.UIAxes,70,12,txt,'fontsize',16)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sine Function')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [-10 15];
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.Limits = [1 10];
            app.AmplitudeSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.AmplitudeSlider.Value = 5;
            app.WavelengthSliderLabel = uilabel(app.UIFigure);
            app.WavelengthSliderLabel.Text = 'Wavelength';
            app.WavelengthSlider = uislider(app.UIFigure);
            app.WavelengthSlider.Limits = [10 200];
            app.WavelengthSlider.MajorTicks = [50 100 150 200];
            app.WavelengthSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.WavelengthSlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.WavelengthSlider.Value = 50;
            app.PhaseSliderLabel = uilabel(app.UIFigure);
            app.PhaseSliderLabel.Text = 'Phase';
            app.PhaseSlider = uislider(app.UIFigure);
            app.PhaseSlider.Limits = [-1 1];
            app.PhaseSlider.MajorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.PhaseSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.PhaseSlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app005

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end