classdef app007 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Lamp                   matlab.ui.control.Lamp
        PLOTSButtonGroup       matlab.ui.container.ButtonGroup
        vButton                matlab.ui.control.RadioButton
        xButton                matlab.ui.control.RadioButton
        phaseSlider            matlab.ui.control.Slider
        phaseSliderLabel       matlab.ui.control.Label
        wavelengthSlider       matlab.ui.control.Slider
        wavelengthSliderLabel  matlab.ui.control.Label
        amplitudeSlider        matlab.ui.control.Slider
        amplitudeSliderLabel   matlab.ui.control.Label
        STARTButton            matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       A
       wL
       phi
    end
    
    methods (Access = public)
        
        
%         function app.execute(A)
%         x  = linspace(0,10,999);
%         y = app.A.*x;
%         plot(app.UIAxes,x,y)
%         end
        
    function execute(app)
        clc
         close all
          app.A   =   app.amplitudeSlider.Value; 
          app.wL  =  app.wavelengthSlider.Value;
          app.phi = app.phaseSlider.Value;
          x  = linspace(0,100,999);
          
          if app.xButton.Value == 1
            flagF = 1;
          else
            flagF = 2;
          end
              
       if flagF == 1
          y = sine(app,x);
        % y = app.A.*sin(2*pi*app.x/app.wL + app.phi);
         plot(app.UIAxes,x,y,'b','linewidth',2)
         app.UIAxes.YLim = [-10 15];
         txt = sprintf('A = %4.1f \n' ,app.A);
         text(app.UIAxes,10,12,txt,'fontsize',16)
         txt = sprintf('\\lambda = %4.1f \n' ,app.wL);
         text(app.UIAxes,40,12,txt,'fontsize',16)
         txt = sprintf('\\phi / \\pi = %4.1f \n' ,app.phi);
         text(app.UIAxes,70,12,txt,'fontsize',16)
       end
       
        if flagF == 2
         % y = sine(app,x);
         k = (2*pi)./app.wL;
         y = k .* app.A.*cos(k*x + app.phi);
         plot(app.UIAxes,x,y,'b','linewidth',2)
         app.UIAxes.YLim = [-1.5 2];
         txt = sprintf('A = %4.1f \n' ,app.A);
         text(app.UIAxes,10,1.4,txt,'fontsize',16)
         txt = sprintf('\\lambda = %4.1f \n' ,app.wL);
         text(app.UIAxes,40,1.4,txt,'fontsize',16)
         txt = sprintf('\\phi / \\pi = %4.1f \n' ,app.phi);
         text(app.UIAxes,70,1.4,txt,'fontsize',16)
       end
       
       
        end
    end
    
    methods (Access = private)
        
        function y = sine(app,x)
            y = app.A.*sin(2*pi*x/app.wL + app.phi);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.execute
             app.Lamp.Color = 'g';
             
        end

        % Callback function: STARTButton, amplitudeSlider
        function amplitudeSliderValueChanged(app, event)
            app.A = app.amplitudeSlider.Value;
            app.execute
            
        end

        % Value changing function: amplitudeSlider
        function amplitudeSliderValueChanging(app, event)
            app.A = event.Value;
            app.execute
            
        end

        % Value changed function: wavelengthSlider
        function wavelengthSliderValueChanged(app, event)
           app.wL = app.wavelengthSlider.Value;
             app.execute
             
        end

        % Value changing function: wavelengthSlider
        function wavelengthSliderValueChanging(app, event)
            app.wL = event.Value;
            app.execute
            
        end

        % Value changed function: phaseSlider
        function phaseSliderValueChanged(app, event)
            app.phi = app.phaseSlider.Value;
            app.execute
            
        end

        % Value changing function: phaseSlider
        function phaseSliderValueChanging(app, event)
            app.phi = event.Value;
            app.execute
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SINE FUNCTION')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [-10 15];
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @amplitudeSliderValueChanged, true);
            app.STARTButton.Text = 'START';
            app.amplitudeSliderLabel = uilabel(app.UIFigure);
            app.amplitudeSliderLabel.Text = 'amplitude';
            app.amplitudeSlider = uislider(app.UIFigure);
            app.amplitudeSlider.Limits = [1 10];
            app.amplitudeSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.amplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @amplitudeSliderValueChanged, true);
            app.amplitudeSlider.ValueChangingFcn = createCallbackFcn(app, @amplitudeSliderValueChanging, true);
            app.amplitudeSlider.Value = 5;
            app.wavelengthSliderLabel = uilabel(app.UIFigure);
            app.wavelengthSliderLabel.Text = 'wavelength';
            app.wavelengthSlider = uislider(app.UIFigure);
            app.wavelengthSlider.Limits = [10 200];
            app.wavelengthSlider.MajorTicks = [0 25 50 75 100 125 150 175 200];
            app.wavelengthSlider.ValueChangedFcn = createCallbackFcn(app, @wavelengthSliderValueChanged, true);
            app.wavelengthSlider.ValueChangingFcn = createCallbackFcn(app, @wavelengthSliderValueChanging, true);
            app.wavelengthSlider.Value = 50;
            app.phaseSliderLabel = uilabel(app.UIFigure);
            app.phaseSliderLabel.Text = 'phase';
            app.phaseSlider = uislider(app.UIFigure);
            app.phaseSlider.Limits = [-1 1];
            app.phaseSlider.MajorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.phaseSlider.ValueChangedFcn = createCallbackFcn(app, @phaseSliderValueChanged, true);
            app.phaseSlider.ValueChangingFcn = createCallbackFcn(app, @phaseSliderValueChanging, true);
            app.PLOTSButtonGroup = uibuttongroup(app.UIFigure);
            app.PLOTSButtonGroup.Title = 'PLOTS';
            app.xButton = uiradiobutton(app.PLOTSButtonGroup);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.vButton = uiradiobutton(app.PLOTSButtonGroup);
            app.vButton.Text = 'v';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app007

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end