classdef app009 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        phaseSlider            matlab.ui.control.Slider
        phaseSliderLabel       matlab.ui.control.Label
        wavelengthSlider       matlab.ui.control.Slider
        wavelengthSliderLabel  matlab.ui.control.Label
        amplitudeSlider        matlab.ui.control.Slider
        amplitudeSliderLabel   matlab.ui.control.Label
        STARTButton            matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function STARTButtonPushed(app, event)
            A = 5;
            wL = 50;
            phi = 0;
            x = linspace(0,100,999);
            y = A.*sin(2*pi*x/wL + phi);

            plot(app.UIAxes,x,y)
            txt = sprintf('A = %4.1f \n' ,A);
            text(app.UIAxes,10,8,txt)
            text(app.UIAxes,10,6,num2str(wL,'%4.1f'))
            text(app.UIAxes,10,4,num2str(phi,'%4.2f'))


        end

        % Callback function
        function amplitudeSliderValueChanged(app, event)
           
            
        end

        % Callback function
        function amplitudeSliderValueChanging(app, event)
          
            
        end

        % Callback function: STARTButton, amplitudeSlider, amplitudeSlider, 
        % phaseSlider, phaseSlider, wavelengthSlider, wavelengthSlider
        function execute(app, event)
           
            A =   app.amplitudeSlider.Value;
            wL =  app.wavelengthSlider.Value;
            phi = app.phaseSlider.Value;
            
            phi = phi*pi;
            x = linspace(0,100,999);
            y = A.*sin(2*pi*x/wL + phi);
            plot(app.UIAxes,x,y,'b','linewidth',2)
            
            txt = sprintf('A = %4.1f \n' ,A);
            text(app.UIAxes,10,12,txt,'fontsize',16)
            txt = sprintf('\\lambda = %4.1f \n' ,wL);
            text(app.UIAxes,40,12,txt,'fontsize',16)
            txt = sprintf('\\phi / \\pi = %4.1f \n' ,phi);
            text(app.UIAxes,70,12,txt,'fontsize',16)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SINE FUNCTION')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [-10 15];
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @execute, true);
            app.STARTButton.Text = 'START';
            app.amplitudeSliderLabel = uilabel(app.UIFigure);
            app.amplitudeSliderLabel.Text = 'amplitude';
            app.amplitudeSlider = uislider(app.UIFigure);
            app.amplitudeSlider.Limits = [1 10];
            app.amplitudeSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.amplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @execute, true);
            app.amplitudeSlider.ValueChangingFcn = createCallbackFcn(app, @execute, true);
            app.amplitudeSlider.Value = 5;
            app.wavelengthSliderLabel = uilabel(app.UIFigure);
            app.wavelengthSliderLabel.Text = 'wavelength';
            app.wavelengthSlider = uislider(app.UIFigure);
            app.wavelengthSlider.Limits = [10 200];
            app.wavelengthSlider.MajorTicks = [10 50 100 150 200];
            app.wavelengthSlider.ValueChangedFcn = createCallbackFcn(app, @execute, true);
            app.wavelengthSlider.ValueChangingFcn = createCallbackFcn(app, @execute, true);
            app.wavelengthSlider.Value = 50;
            app.phaseSliderLabel = uilabel(app.UIFigure);
            app.phaseSliderLabel.Text = 'phase';
            app.phaseSlider = uislider(app.UIFigure);
            app.phaseSlider.Limits = [-1 1];
            app.phaseSlider.MajorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.phaseSlider.ValueChangedFcn = createCallbackFcn(app, @execute, true);
            app.phaseSlider.ValueChangingFcn = createCallbackFcn(app, @execute, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app009

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end