classdef app101 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Switch2                matlab.ui.control.ToggleSwitch
        Switch_2               matlab.ui.control.RockerSwitch
        Lamp_2                 matlab.ui.control.Lamp
        Switch                 matlab.ui.control.Switch
        Newtons2ndLawLabel     matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        OKLabel                matlab.ui.control.Label
        Lamp                   matlab.ui.control.Lamp
        WeightNEditField       matlab.ui.control.NumericEditField
        WeightNEditFieldLabel  matlab.ui.control.Label
        accms2EditField        matlab.ui.control.NumericEditField
        accms2EditFieldLabel   matlab.ui.control.Label
        masskgEditField        matlab.ui.control.NumericEditField
        masskgEditFieldLabel   matlab.ui.control.Label
        CalculateButton        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            close all
            m = app.masskgEditField.Value;
            a = app.accms2EditField.Value;
            
            F = m*a;
            
            app.WeightNEditField.Value = F;
            
            if F >= 0 && F<= 500
                app.Lamp.Color = 'g';
                app.OKLabel.Text = 'OK';
                app.OKLabel.FontColor = 'g';
            elseif F > 500 && F < 1000
                app.Lamp.Color = [0.93 0.69 0.13];
                app.OKLabel.Text = 'careful';
                app.OKLabel.FontColor = [0.93 0.69 0.13];
            elseif F > 1000
                app.Lamp.Color = 'r';
                app.OKLabel.Text = 'DANGER';
                app.OKLabel.FontColor = 'r';
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
        % strcmp  string comparison    
        if strcmp(value,'On')
               app.Lamp_2.Color = 'g';
           else
               app.Lamp_2.Color = 'r';
        end
           
        
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
        if strcmp(value,'On')
               app.Lamp_2.Color = 'g';
           else
               app.Lamp_2.Color = 'm';
        end
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            value = app.Switch2.Value;
        if strcmp(value,'On')
               app.Lamp_2.Color = 'g';
           else
               app.Lamp_2.Color = 'b';
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.masskgEditFieldLabel = uilabel(app.Panel);
            app.masskgEditFieldLabel.Text = 'mass  [kg]';
            app.masskgEditField = uieditfield(app.Panel, 'numeric');
            app.accms2EditFieldLabel = uilabel(app.Panel);
            app.accms2EditFieldLabel.Text = 'acc  [m/s^2]';
            app.accms2EditField = uieditfield(app.Panel, 'numeric');
            app.WeightNEditFieldLabel = uilabel(app.Panel);
            app.WeightNEditFieldLabel.Text = 'Weight  [N]';
            app.WeightNEditField = uieditfield(app.Panel, 'numeric');
            app.Lamp = uilamp(app.Panel);
            app.OKLabel = uilabel(app.Panel);
            app.OKLabel.Text = 'OK';
            app.Newtons2ndLawLabel = uilabel(app.UIFigure);
            app.Newtons2ndLawLabel.Text = 'Newton''s 2nd Law';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Switch_2 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch2 = uiswitch(app.UIFigure, 'toggle');
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app101

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end