classdef app2201 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RESTButton               matlab.ui.control.Button
        ReversalpotentialsLabel  matlab.ui.control.Label
        EL65mVLabel              matlab.ui.control.Label
        EK77mVLabel              matlab.ui.control.Label
        ENa50mVLabel             matlab.ui.control.Label
        CurrentstimulusLabel     matlab.ui.control.Label
        IonicconductancesLabel   matlab.ui.control.Label
        JextSlider               matlab.ui.control.Slider
        JextSliderLabel          matlab.ui.control.Label
        gKSlider                 matlab.ui.control.Slider
        gKSliderLabel            matlab.ui.control.Label
        gNaSlider                matlab.ui.control.Slider
        gNaSliderLabel           matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            execute(app)
        end

        % Value changed function: gNaSlider
        function gNaSliderValueChanged(app, event)
           execute(app)
        end

        % Value changed function: gKSlider
        function gKSliderValueChanged(app, event)
          execute(app)
            
        end

        % Value changing function: gKSlider, gNaSlider
        function execute(app, event)
           
            GNa   =   app.gNaSlider.Value;
            GK    =   app.gKSlider.Value;
            J     =   app.JextSlider.Value;
               
            num = 10001;
            ENa = 50e-3;
            EK  = -77e-3;
            EL  = -65e-3;
            tMax = 20e-3;
             
          % Specific conductances     [S/cm^2]
            gNa = GNa*120e-3;
            gK  = GK*36e-3;
            gL = 0e-3; 

         % Resting potneital  [V]
            Vrest = -65e-3;
        
         % Specific capacitance  [F/cm^2]
            cm = 1e-4;
         % Time
            t = linspace(0,tMax,num);
            dt = t(2) - t(1);
           num = 10001;

            V = zeros(num,1); JL = V; JK = V; JNa = V; Jm = V;
            V(1) = Vrest;
            Jext = J*1e-2*ones(num,1);

% Solve ODE
  for n = 1 : num-1
   JL(n)  = gL* (V(n) - EL);
   JK(n)  = gK* (V(n) - EK);
   JNa(n) = gNa*(V(n) - ENa);
   Jm(n)  = JL(n) + JK(n) + JNa(n);
   V(n+1) = V(n) + (dt/cm)*(Jext(n) - Jm(n));
  end


            xP = [0 tMax].*1e3;
            yP = [ENa ENa].*1e3;
            plot(app.UIAxes,xP,yP,'r','linewidth',1)
            hold(app.UIAxes,'on')
            yP = [EK EK].*1e3;
            plot(app.UIAxes,xP,yP,'m','linewidth',1)
            yP = [EL EL].*1e3;
            plot(app.UIAxes,xP,yP,'k','linewidth',1)

            xP = t.*1e3; yP = V*1e3;
            plot(app.UIAxes,xP,yP,'b','linewidth',2)

            app.UIAxes.XLim = [0 tMax*1e3];
            app.UIAxes.YLim = [-80 80];

            title(app.UIAxes, 'membrane potential','Color','b')
            xlabel(app.UIAxes,'t  [ms]')
            ylabel(app.UIAxes,'V  [mV]')


        end

        % Value changed function: JextSlider
        function JextSliderValueChanged(app, event)
            execute(app)
            
        end

        % Button pushed function: RESTButton
        function RESTButtonPushed(app, event)
            hold(app.UIAxes,'off')
            execute(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'membrane potential')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [-10 15];
            app.gNaSliderLabel = uilabel(app.UIFigure);
            app.gNaSliderLabel.Text = 'gNa';
            app.gNaSlider = uislider(app.UIFigure);
            app.gNaSlider.Limits = [0 1];
            app.gNaSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.gNaSlider.ValueChangedFcn = createCallbackFcn(app, @gNaSliderValueChanged, true);
            app.gNaSlider.ValueChangingFcn = createCallbackFcn(app, @execute, true);
            app.gNaSlider.Value = 1;
            app.gKSliderLabel = uilabel(app.UIFigure);
            app.gKSliderLabel.Text = 'gK';
            app.gKSlider = uislider(app.UIFigure);
            app.gKSlider.Limits = [0 1];
            app.gKSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.gKSlider.ValueChangedFcn = createCallbackFcn(app, @gKSliderValueChanged, true);
            app.gKSlider.ValueChangingFcn = createCallbackFcn(app, @execute, true);
            app.JextSliderLabel = uilabel(app.UIFigure);
            app.JextSliderLabel.Text = 'Jext';
            app.JextSlider = uislider(app.UIFigure);
            app.JextSlider.Limits = [0 1];
            app.JextSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.JextSlider.ValueChangedFcn = createCallbackFcn(app, @JextSliderValueChanged, true);
            app.IonicconductancesLabel = uilabel(app.UIFigure);
            app.IonicconductancesLabel.Text = {'Ionic conductances'; ''};
            app.CurrentstimulusLabel = uilabel(app.UIFigure);
            app.CurrentstimulusLabel.Text = 'Current stimulus';
            app.ENa50mVLabel = uilabel(app.UIFigure);
            app.ENa50mVLabel.Text = 'E(Na) = +50 mV';
            app.EK77mVLabel = uilabel(app.UIFigure);
            app.EK77mVLabel.Text = 'E(K) = - 77 mV';
            app.EL65mVLabel = uilabel(app.UIFigure);
            app.EL65mVLabel.Text = 'E(L) = -65 mV';
            app.ReversalpotentialsLabel = uilabel(app.UIFigure);
            app.ReversalpotentialsLabel.Text = 'Reversal potentials';
            app.RESTButton = uibutton(app.UIFigure, 'push');
            app.RESTButton.ButtonPushedFcn = createCallbackFcn(app, @RESTButtonPushed, true);
            app.RESTButton.Text = 'REST';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2201

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end