classdef appQM001 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SQUARESLOPINGPOTENTIALWELLLabel  matlab.ui.control.Label
        UPDATEButton      matlab.ui.control.Button
        PLOTSpinner       matlab.ui.control.Spinner
        PLOTSpinnerLabel  matlab.ui.control.Label
        Panel             matlab.ui.container.Panel
        qn2Spinner        matlab.ui.control.Spinner
        qn2SpinnerLabel   matlab.ui.control.Label
        qn1Spinner        matlab.ui.control.Spinner
        qn1SpinnerLabel   matlab.ui.control.Label
        U2Label           matlab.ui.control.Label
        U1Label           matlab.ui.control.Label
        wellwidthLabel    matlab.ui.control.Label
        Slider3           matlab.ui.control.Slider
        Slider            matlab.ui.control.Slider
        Slider2           matlab.ui.control.Slider
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       x
       U
       xMin
       xMax
       E
       n
       PSI
       psi
       N
       qn1
       qn2
       prob
       xavg
       x2avg
       ipavg
       ip2avg
       Uavg
       Kavg
       Eavg
       deltax
       deltaip
       dxdp
       ortho
    end
    
    methods (Access = private)
                   
 % =====================================================================       
 function plot1(app)
             cla(app.UIAxes,'reset')
             hold(app.UIAxes, 'off')
             plot(app.UIAxes,app.x,app.U,'b','linewidth',2)
             hold(app.UIAxes, 'on')
             cnmax = length(app.E);
         for cn = 1 : cnmax
             ys(1) = app.E(cn);
             ys(2) = ys(1);
             plot(app.UIAxes,[app.xMin app.xMax],ys,'r','LineWidth',2);
             app.UIAxes.XLim = [-0.1 0.1];
             app.UIAxes.YLim = [-500 10];
         end %for 
             xlabel(app.UIAxes,'x  [ nm ]','FontSize',16)
             ylabel(app.UIAxes,'energy  [ eV ]','FontSize',16)
             app.UIAxes.FontSize = 16;
             app.UIAxes.Box = 'on';
 end
% =====================================================================

 function plot2(app)
        cla(app.UIAxes)
        hold(app.UIAxes, 'off')
        app.n
        Ws = 1.5;
        Ps = 1;
        hold(app.UIAxes, 'on')
        c = 1;
        xP = linspace(0,49,app.N);
        yP = 70 + Ws.*app.psi(:,c);
        plot(app.UIAxes,xP, yP,'b','linewidth',2) 
         
        xP = linspace(50,100,app.N);
        yP = app.psi(:,c).*app.psi(:,c);
        yP = 60 + Ps.*yP;
        plot(app.UIAxes,xP, yP,'r','linewidth',2) 
        title(app.UIAxes,'  \psi                      \psi^2') 
        
        c = c+1;   % 2
        if c < app.n+1
            xP = linspace(0,49,app.N);
            yP = 50 + Ws.*app.psi(:,c);
            plot(app.UIAxes,xP, yP,'b','linewidth',2) 
          
            xP = linspace(50,100,app.N);
            yP = app.psi(:,c).*app.psi(:,c);
            yP = 40 + Ps.*yP;
            plot(app.UIAxes,xP, yP,'r','linewidth',2) 
        end
            
        c = c+1;   % 3
        if c < app.n+1
            xP = linspace(0,49,app.N);
            yP = 30 + Ws.*app.psi(:,c);
            plot(app.UIAxes,xP, yP,'b','linewidth',2) 
          
            xP = linspace(50,100,app.N);
            yP = app.psi(:,c).*app.psi(:,c);
            yP = 20 + Ps.*yP;
            plot(app.UIAxes,xP, yP,'r','linewidth',2) 
            app.UIAxes.Box = 'on';
        end
        
        c = c+1;   % 4
        if c < app.n+1
            xP = linspace(0,49,app.N);
            yP = 10 + Ws.*app.psi(:,c);
            plot(app.UIAxes,xP, yP,'b','linewidth',2) 
          
            xP = linspace(50,100,app.N);
            yP = app.psi(:,c).*app.psi(:,c);
            yP = 0 + Ps.*yP;
            plot(app.UIAxes,xP, yP,'r','linewidth',2) 
        end
         
        app.UIAxes.XLim = [0 100];
        app.UIAxes.YLim = [-10 100];
        axis(app.UIAxes,'off')
     end  % plot2(app)
     
 % =====================================================================      
 function plot3(app)
         cla(app.UIAxes,'reset')
         hold(app.UIAxes, 'off')
         app.qn1 = app.qn1Spinner.Value;
         if app.qn1 > app.n
             app.qn1 = app.n;
             app.qn1Spinner.Value = app.n;
         end
         app.qn2 = app.qn2Spinner.Value;
         if app.qn2 > app.n
             app.qn2 = app.n;
             app.qn2Spinner.Value = app.n;
         end
         
      yyaxis(app.UIAxes,'left')
         xP = app.x; yP = app.psi(:,app.qn1);
         plot(app.UIAxes,xP,yP,'b-','linewidth',2)  
         hold(app.UIAxes, 'on')
         yP = app.psi(:,app.qn2);
         plot(app.UIAxes,xP,yP,'r-','linewidth',2) 
         app.UIAxes.XLim = [-0.1 0.1];
         app.UIAxes.YLim = [-5 5];
         app.UIAxes.YTick = -5:1:5;
         xlabel(app.UIAxes,'x')
         ylabel(app.UIAxes,'\psi','Rotation',0)
         title(app.UIAxes,'wavefunctions  \psi')
         set(app.UIAxes.YAxis, 'Color','k' )
         axis(app.UIAxes,'on')
         app.UIAxes.FontSize = 16;
      yyaxis(app.UIAxes,'right')
         yP = app.U;
         plot(app.UIAxes,xP,yP,'m','linewidth',1)  
         ylabel(app.UIAxes,'U  [eV]')
         app.UIAxes.Box = 'on';
end  % end plot3
     
% =====================================================================      
function plot4(app)
         cla(app.UIAxes,'reset')
         hold(app.UIAxes, 'off')
         app.qn1 = app.qn1Spinner.Value;
         if app.qn1 > app.n
             app.qn1 = app.n;
             app.qn1Spinner.Value = app.n;
         end
         app.qn2 = app.qn2Spinner.Value;
         if app.qn2 > app.n
             app.qn2 = app.n;
             app.qn2Spinner.Value = app.n;
         end
         
      yyaxis(app.UIAxes,'left')
         xP = app.x;
         yP = app.psi(:,app.qn1).*app.psi(:,app.qn2);
         plot(app.UIAxes,xP,yP,'b-','linewidth',2)  
         app.UIAxes.XLim = [-0.1 0.1];
         app.UIAxes.YLim = [-20 20];
         app.UIAxes.YTick = -20:5:20;
         xlabel(app.UIAxes,'x')
         ylabel(app.UIAxes,'\psi_{qn1}*\psi_{qn2}')
         txt = sprintf('< \\psi_{qn1} \\psi_{qn2} > = %2.2f  \n',app.ortho);
         title(app.UIAxes,txt)
         set(app.UIAxes.YAxis, 'Color','k' )
         axis(app.UIAxes,'on')
         app.UIAxes.FontSize = 16;
      yyaxis(app.UIAxes,'right')
         yP = app.U;
         plot(app.UIAxes,xP,yP,'m','linewidth',1)  
         ylabel(app.UIAxes,'U  [eV]')
         app.UIAxes.Box = 'on';
 end  % end plot4

% =====================================================================      
function plot5(app)
         cla(app.UIAxes,'reset')
         hold(app.UIAxes, 'off')
         app.qn1 = app.qn1Spinner.Value;
         if app.qn1 > app.n
             app.qn1 = app.n;
             app.qn1Spinner.Value = app.n;
         end
         
      yyaxis(app.UIAxes,'left')
         xP = app.x;
         yP = app.psi(:,app.qn1).*app.psi(:,app.qn1);
         fill(app.UIAxes,xP,yP,'b-','linewidth',2)  
         app.UIAxes.XLim = [-0.1 0.1];
         app.UIAxes.YLim = [-10 20];
         app.UIAxes.YTick = 0:5:20;
         xlabel(app.UIAxes,'x')
         ylabel(app.UIAxes,'\psi_{qn1}*\psi_{qn1}')
         title(app.UIAxes,'Probabilty Density   \psi_{qn1}')
         set(app.UIAxes.YAxis, 'Color','k' )
         axis(app.UIAxes,'on')
         app.UIAxes.FontSize = 16;
         
         hold(app.UIAxes, 'on')
         num1 = 5001;
   
         for c = 1 : num1
             xIndex = ceil(1+(app.N-2)*rand(1,1));
             yIndex = -8 + (-2 + 8)*rand(1,1);
             pIndex = max(yP)*rand(1,1);
           if pIndex < yP(xIndex)
             plot(app.UIAxes,app.x(xIndex),yIndex,'s','MarkerSize',2, ...
             'MarkerFaceColor','b','MarkerEdgeColor','none');
           end
        end
         
      yyaxis(app.UIAxes,'right')
         yP = app.U;
         plot(app.UIAxes,xP,yP,'m','linewidth',1)  
         ylabel(app.UIAxes,'U  [eV]')
         app.UIAxes.Box = 'on';
 end  % end plot5

 
 function plot6(app)
          cla(app.UIAxes,'reset')
          EB = zeros(10,1);
          for c = 1 : app.n
            EB(c) = - app.E(c);
          end
          FS = 16;
          text(app.UIAxes,2,95,'BINDING ENERGIES  [eV]','FontWeight', ...
              'bold','fontsize',16)
          hx = 2; hy = 85; dhy = 7;
             txt = sprintf('EB_1 = %2.3f \n', EB(1));
             text(app.UIAxes,hx,hy,txt,'fontsize',FS);
          hx = 35; 
             txt = sprintf('EB_2 = %2.3f \n', EB(2));
             text(app.UIAxes,hx,hy,txt,'fontsize',FS);
          hx = 68; 
             txt = sprintf('EB_3 = %2.3f \n', EB(3));
             text(app.UIAxes,hx,hy,txt,'fontsize',FS);
          hx = 2; hy = hy - dhy;
             txt = sprintf('EB_4 = %2.3f \n', EB(4));
             text(app.UIAxes,hx,hy,txt,'fontsize',FS);
          hx = 35; 
             txt = sprintf('EB_5 = %2.3f \n', EB(5));
             text(app.UIAxes,hx,hy,txt,'fontsize',FS);
          hx = 68; 
             txt = sprintf('EB_6 = %2.3f \n', EB(6));
             text(app.UIAxes,hx,hy,txt,'fontsize',FS); 
          hx = 2; hy = hy - dhy;
             txt = sprintf('EB_7 = %2.3f \n', EB(7));
             text(app.UIAxes,hx,hy,txt,'fontsize',FS);
          hx = 35; 
             txt = sprintf('EB_8 = %2.3f \n', EB(8));
             text(app.UIAxes,hx,hy,txt,'fontsize',FS);
          hx = 68; 
             txt = sprintf('EB_9 = %2.3f \n', EB(9));
             text(app.UIAxes,hx,hy,txt,'fontsize',FS); 
          hx = 2; hy = hy - dhy;
             txt = sprintf('EXPECTATION VALUES quantum state qn1 = %2.0f \n', app.qn1);
             text(app.UIAxes,hx,hy,txt,'fontsize',FS,'FontWeight',"bold");    
          hx = 2; hy = hy - dhy;
             txt = sprintf('< x > = %2.3f  nm \n', app.xavg);
             text(app.UIAxes,hx,hy,txt,'fontsize',FS);     
          hx = 2; hy = hy - dhy;
             txt = sprintf('< ip > = %2.2e  N.s \n', app.ipavg);
             text(app.UIAxes,hx,hy,txt,'fontsize',FS);        
          hx = 45;
             txt = sprintf('< ip^2 > = %2.2e  N^2.s^2 \n', app.ip2avg);
             text(app.UIAxes,hx,hy,txt,'fontsize',FS); 
          hx = 2; hy = hy - dhy;
             txt = sprintf('< U > = %2.2f eV \n', app.Uavg);
             text(app.UIAxes,hx,hy,txt,'fontsize',FS);   
          hx = 45; 
             txt = sprintf('< K > = %2.2f eV \n', app.Kavg);
             text(app.UIAxes,hx,hy,txt,'fontsize',FS); 
          hx = 2; hy = hy - dhy;
             txt = sprintf('< E > = %2.2f eV \n', app.Eavg);
             text(app.UIAxes,hx,hy,txt,'fontsize',FS);      
          hx = 2; hy = hy - dhy;
             txt = 'HEISENBERG UNCERATINTY PRINCIPLE';
             text(app.UIAxes,hx,hy,txt,'fontsize',FS, ...
                 'fontWeight','bold');   
           hx = 2; hy = hy - 1.3*dhy;
             txt = sprintf('\\Deltax = %2.2e nm \n',app.deltax);
             text(app.UIAxes,hx,hy,txt,'fontsize',FS);   
          hx = 45; 
             txt = sprintf('\\Deltaip = %2.2e N.s \n',app.deltaip);
             text(app.UIAxes,hx,hy,txt,'fontsize',FS);  
          hx = 2; hy = hy - dhy;
             txt = sprintf('\\Deltax \\Deltap) / hbar = %2.2f  \n',app.dxdp);
             text(app.UIAxes,hx,hy,txt,'fontsize',FS);
          hx = 2; hy = hy - dhy;
             txt = sprintf('ORTHONORMAL STATES qn1 = %2.0f  qn2 = %2.0f \n',app.qn1,app.qn2);
             text(app.UIAxes,hx,hy,txt,'fontsize',FS,'FontWeight',"bold");    
          hx = 2; hy = hy - dhy;
             txt = sprintf('< \\psi_{qn1} \\psi_{qn2} > = %2.2f  \n',app.ortho);
             text(app.UIAxes,hx,hy,txt,'fontsize',FS);
             
          app.UIAxes.XLim = [0 100];
          app.UIAxes.YLim = [0 100];
          axis(app.UIAxes,'off')
 

 end  % plot6
 
%  =======================================================================     
function execute(app)
         app.PLOTSpinner.Value = 1;
         w = app.Slider.Value;
         U1 = -app.Slider2.Value;
         U2 = -app.Slider3.Value;
         app.qn1 = app.qn1Spinner.Value;
         app.qn2 = app.qn2Spinner.Value;    
            
       % Number of grid points (must be an ODD number)
         app.N = 999;
       % x Range  [nm]   
         app.xMin = -0.10;  app.xMax = 0.10;
         app.x = linspace(app.xMin,app.xMax,app.N);
         dx = app.x(2)-app.x(1);
         dx2 = dx^2;
            
      % Elementary charge [C]  
        e = 1.6021766208e-19;
      % Planck constant [ J.s] 
        hbar = 1.054571800e-34; 
      % Electron mass [kg] 
        me = 9.10938356e-31; 
      % Scaling constants
        Ese = e;  Lse = 1e-9;
        Cs = -hbar^2/(2*me) / (Lse^2*Ese); 


%   POTENTIAL ENERGY MATRIX  ******************************************
       L = abs(app.xMin) + abs(app.xMax);   
       w = (w/100)*L;  wB = (L-w)/2;
       NB = round((wB/L)*app.N,0);
       NR = (NB :(app.N-NB))';
       app.U = zeros(app.N,1);
       U_matrix = zeros(app.N-2,app.N-2);
       N1 = NB; N2 = app.N-NB;
       slope = (U2-U1)/(N2-N1);
       intercept = U1 - slope*N1;
       app.U(NR) = slope*NR + intercept;
       
       for cn = 1:(app.N-2)
          U_matrix(cn,cn) = app.U(cn+1);
       end
       
%   KINETIC ENERGY and HAMILTONIAN MATRICES  *************************  
    % Second Derivative Matrix 
      off = ones(app.N-3,1);                 
      SD_matrix = (-2*eye(app.N-2) + diag(off,1) + diag(off,-1))/dx2;
    % KE Matrix
      K_matrix = Cs * SD_matrix;            
    % Hamiltonian Matrix
      H_matrix = K_matrix + U_matrix;
      
%   EIGENVALUES and EIGENFUNCTIONS  **********************************    
      [e_funct, e_values] = eig(H_matrix);

%   All Eigenvalues 1, 2 , ... n  where E_N < 0
      flag = 0;
      app.n = 1;
      while flag == 0
          app.E(app.n) = e_values(app.n,app.n);
          if app.E(app.n) > 0, flag = 1; end 
          app.n = app.n + 1;
      end  
      app.E(app.n-1) = [];
      app.n = app.n-2;

% Corresponding Eigenfunctions 1, 2, ... ,n: Normalizing the wavefunction
    app.psi = zeros(app.N,app.n); area = zeros(1,app.n);
    for cn = 1 : app.n
      app.psi(:,cn) = [0; e_funct(:,cn); 0];
      f = (app.psi(:,cn) .* app.psi(:,cn))';
      a = app.xMin; b = app.xMax;
      area(cn) = Simpson1d(app,f,a,b);
      % curve starts positive
        if app.psi(5,cn) < 0, app.psi(:,cn) = -app.psi(:,cn); end  
    end 
    app.psi = app.psi./sqrt(area);
    probD = app.psi.* app.psi;

    f = probD';
  %  PROB = Simpson1d(app,f,a,b);
    
  %  app.PSI = app.psi(:,app.qn1);
  
%    EXPECTATION VALUES *************************************************
     bra = app.psi(:,app.qn1)';
     Psi = app.psi(:,app.qn1)';

   % Probability
       ket = Psi;
       braket = bra .* ket;
       app.prob = Simpson1d(app,braket,app.xMin,app.xMax);
   % Orthonormal states
       braket = app.psi(:,app.qn1).*app.psi(:,app.qn2);
       app.ortho = Simpson1d(app,braket',app.xMin,app.xMax);

   % Position x
       ket = app.x .* Psi;
       braket = bra .* ket;
       app.xavg = Simpson1d(app,braket,app.xMin,app.xMax);

   % Position x^2
      ket = (app.x.^2) .* Psi;
      braket = bra .* ket;
      app.x2avg = Simpson1d(app,braket,app.xMin,app.xMax);

   % Momentum ip      change length units from nm to m
       ket = gradient(Psi,app.x)* hbar / Lse;
       braket = bra .* ket;
       app.ipavg = Simpson1d(app,braket,app.xMin,app.xMax);

   % Momentum ip^2    change length unit from nm to m
       psi1 = gradient(Psi,app.x);
       ket = -gradient(psi1,app.x) * hbar^2 / Lse^2;
       braket = bra .* ket;
       app.ip2avg = Simpson1d(app,braket,app.xMin,app.xMax);

   % Potential energy U
       ket = app.U' .* Psi;
       braket = bra .* ket;
       app.Uavg = Simpson1d(app,braket,app.xMin,app.xMax);

   % Kinetic energy K
       psi1 = gradient(Psi,app.x);
       ket = - (hbar^2/(2*me))* gradient(psi1,app.x) / (Lse^2*Ese);
       braket = bra .* ket;
       app.Kavg = Simpson1d(app,braket,app.xMin,app.xMax);   
       
   % Total energy
       psi1 = gradient(Psi,app.x);
       ket = - (hbar^2/(2*me))* gradient(psi1,app.x) / (Lse^2*Ese) + (app.U.*Psi')';	
       braket = bra .* ket;
       app.Eavg = Simpson1d(app,braket,app.xMin,app.xMax);

   % Calculate uncertainites 
      app.deltax = sqrt(app.x2avg - app.xavg^2) * Lse;         % length units nm to m
      app.deltaip = sqrt(app.ip2avg + app.ipavg^2);            % unit N.s
      app.dxdp = (app.deltax * app.deltaip)/hbar;              % N.s.m = J.s   (SI unit)

%    ********************************************************************

    app.PLOTSpinner.Value = 1;
    plot1(app) 
   
       
end  % execute

% =====================================================================
function integral = Simpson1d(~,f,a,b)
  % [1D] integration - Simpson's 1/3 rule
  %      f function    a = lower bound    b = upper bound
  %      Must have odd number of data points
  %      Simpson's coefficients   1 4 2 4 ... 2 4 1

    numS = length(f);               % number of data points
  if mod(numS,2) == 1
      sc = 2*ones(numS,1);
      sc(2:2:numS-1) = 4;
      sc(1) = 1; sc(numS) = 1;
      h = (b-a)/(numS-1);
      integral = (h/3) * f * sc;
  else
      integral = 'Length of function must be an ODD number'; 
  end
end 

% =====================================================================
    end   % end METHODS


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   % clear
   close all
   clc
   app.N = 999;
   app.xMin = -0.10;  app.xMax = 0.10;
   app.x = linspace(app.xMin,app.xMax,app.N);
   L = abs(app.xMin) + abs(app.xMax);   
   
   w = (50/100)*L;  wB = (L-w)/2;
   NB = round((wB/L)*app.N,0);
   NR = (NB :(app.N-NB))';
   app.U = zeros(app.N,1);
   U1 = -400; U2 = -100;
   N1 = NB; N2 = app.N-NB;
   slope = (U2-U1)/(N2-N1);
   intercept = U1 - slope*N1;
   app.U(NR) = slope*NR + intercept;
   plot(app.UIAxes,app.x,app.U,'b','linewidth',2) 
   xlabel(app.UIAxes, 'x  [nm]')
   ylabel(app.UIAxes, 'U  [eV]')
   app.UIAxes.FontSize = 16;
   title(app.UIAxes, 'Potential Energy  ')
   
        end

        % Value changed function: PLOTSpinner
        function PLOTSpinnerValueChanged(app, event)
            z = app.PLOTSpinner.Value;
                      
            if z == 1; plot1(app); end
            if z == 2; plot2(app); end
            if z == 3; plot3(app); end
            if z == 4; plot4(app); end
            if z == 5; plot5(app); end
            if z == 6; plot6(app); end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
           % value = app.Slider.Value;
            execute(app)
            
            
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            %value = app.Slider2.Value;
            execute(app)
            
        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
          %  value = app.Slider3.Value;
            execute(app)
        end

        % Value changed function: qn1Spinner
        function qn1SpinnerValueChanged(app, event)
         %   value = app.qn1Spinner.Value;
             execute(app)
            
        end

        % Value changed function: qn2Spinner
        function qn2SpinnerValueChanged(app, event)
           % value = app.qn2Spinner.Value;
            execute(app)
            
            
        end

        % Button pushed function: UPDATEButton
        function UPDATEButtonPushed(app, event)
            execute(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SQUARE / SLOPING WELL')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-0.1 0.1];
            app.UIAxes.YLim = [-510 10];
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [10 500];
            app.Slider2.MajorTicks = [100 200 300 400 500];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.Value = 400;
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [10 90];
            app.Slider.MajorTicks = [10 20 30 40 50 60 70 80 90];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 50;
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.Limits = [10 500];
            app.Slider3.MajorTicks = [10 100 200 300 400 500];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            app.Slider3.Value = 400;
            app.wellwidthLabel = uilabel(app.UIFigure);
            app.wellwidthLabel.Text = '% well width';
            app.U1Label = uilabel(app.UIFigure);
            app.U1Label.Text = 'U1';
            app.U2Label = uilabel(app.UIFigure);
            app.U2Label.Text = 'U2';
            app.Panel = uipanel(app.UIFigure);
            app.qn1SpinnerLabel = uilabel(app.Panel);
            app.qn1SpinnerLabel.Text = 'qn1';
            app.qn1Spinner = uispinner(app.Panel);
            app.qn1Spinner.Limits = [1 10];
            app.qn1Spinner.ValueChangedFcn = createCallbackFcn(app, @qn1SpinnerValueChanged, true);
            app.qn1Spinner.Value = 1;
            app.qn2SpinnerLabel = uilabel(app.Panel);
            app.qn2SpinnerLabel.Text = 'qn2';
            app.qn2Spinner = uispinner(app.Panel);
            app.qn2Spinner.Limits = [1 10];
            app.qn2Spinner.ValueChangedFcn = createCallbackFcn(app, @qn2SpinnerValueChanged, true);
            app.qn2Spinner.Value = 2;
            app.PLOTSpinnerLabel = uilabel(app.UIFigure);
            app.PLOTSpinnerLabel.Text = 'PLOT';
            app.PLOTSpinner = uispinner(app.UIFigure);
            app.PLOTSpinner.Limits = [1 6];
            app.PLOTSpinner.ValueChangedFcn = createCallbackFcn(app, @PLOTSpinnerValueChanged, true);
            app.PLOTSpinner.Value = 1;
            app.UPDATEButton = uibutton(app.UIFigure, 'push');
            app.UPDATEButton.ButtonPushedFcn = createCallbackFcn(app, @UPDATEButtonPushed, true);
            app.UPDATEButton.Text = 'UPDATE';
            app.SQUARESLOPINGPOTENTIALWELLLabel = uilabel(app.UIFigure);
            app.SQUARESLOPINGPOTENTIALWELLLabel.Text = 'SQUARE / SLOPING POTENTIAL WELL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appQM001

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end