classdef appQM001A < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        PLOTSpinner       matlab.ui.control.Spinner
        PLOTSpinnerLabel  matlab.ui.control.Label
        Panel             matlab.ui.container.Panel
        qn2Spinner        matlab.ui.control.Spinner
        qn2SpinnerLabel   matlab.ui.control.Label
        qn1Spinner        matlab.ui.control.Spinner
        qn1SpinnerLabel   matlab.ui.control.Label
        U2Label           matlab.ui.control.Label
        U1Label           matlab.ui.control.Label
        wellwidthLabel    matlab.ui.control.Label
        Slider3           matlab.ui.control.Slider
        Slider            matlab.ui.control.Slider
        Slider2           matlab.ui.control.Slider
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       x
       U
    end
    
    methods (Access = private)
                   
        
        function plot1(app)
            plot(app.UIAxes,app.x,app.U,'b','linewidth',2)
           % app.UIAxes.XLim = [xMin xMax];
        end
             
       function plot2(app)
          %  x = 1:10;
          %  y = x.^0.5;
          %  plot(app.UIAxes,x,y,'b','linewidth',2)
       end


        function execute(app)
            w = app.Slider.Value;
            U1 = -app.Slider2.Value;
            U2 = -app.Slider3.Value;
            qn1 = app.qn1Spinner.Value;
            qn2 = app.qn2Spinner.Value;    
            
        % Number of grid points (must be an ODD number)
            N = 999;
        % x Range  [nm]   
            xMin = -0.10;  xMax = 0.10;
            app.x = linspace(xMin,xMax,N);
            dx = app.x(2)-app.x(1);
            dx2 = dx^2;
            
        % Elementary charge [C]  
            e = 1.6021766208e-19;
        % Planck constant [ J.s] / Reduced Planck's constant
            h = 6.626070040e-34;            
            hbar = 1.054571800e-34; 
        % Electron mass [kg] 
            me = 9.10938356e-31; 
        % Scaling constants
            Es = e;  Ls = 1e-9;
            Cs = -hbar^2/(2*me) / (Ls^2*Es); 

% *********************************************************************
% POTENTIAL ENERGY MATRIX
% *********************************************************************
% Square Well dimensions: boundary wB NB and wW Nw 
    L = abs(xMin) + abs(xMax);   
    w = (w/100)*L;  wB = (L-w)/2;
    NB = round((wB/L)*N,0);
    NR = (NB :(N-NB))';
    app.U = zeros(N,1);
    U_matrix = zeros(N-2,N-2);
    N1 = NB; N2 = N-NB;
    slope = (U2-U1)/(N2-N1);
    intercept = U1 - slope*N1;
    app.U(NR) = slope*NR + intercept;
    plot1(app)        
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%    clear
%    close all
%    clc
   N = 999;
   xMin = -0.10;  xMax = 0.10;
   x = linspace(xMin,xMax,N);
   L = abs(xMin) + abs(xMax);   
   
   w = (50/100)*L;  wB = (L-w)/2;
   NB = round((wB/L)*N,0);
   NR = (NB :(N-NB))';
   U = zeros(N,1);
   U1 = -400; U2 = -100;
   N1 = NB; N2 = N-NB;
   slope = (U2-U1)/(N2-N1);
   intercept = U1 - slope*N1;
   U(NR) = slope*NR + intercept;
   plot(app.UIAxes,x,U,'b','linewidth',2) 
   xlabel(app.UIAxes, 'x  [nm]')
   ylabel(app.UIAxes, 'U  [eV]')
   app.UIAxes.FontSize = 14;
   title(app.UIAxes, 'Potential Energy  ')
   
        end

        % Value changed function: PLOTSpinner
        function PLOTSpinnerValueChanged(app, event)
            z = app.PLOTSpinner.Value;
            
            
            if z == 1; plot1(app); end
            if z == 2; plot2(app); end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
           % value = app.Slider.Value;
            execute(app)
            
            
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            %value = app.Slider2.Value;
            execute(app)
            
        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
          %  value = app.Slider3.Value;
            execute(app)
        end

        % Value changed function: qn1Spinner
        function qn1SpinnerValueChanged(app, event)
         %   value = app.qn1Spinner.Value;
            execute(app)
            
        end

        % Value changed function: qn2Spinner
        function qn2SpinnerValueChanged(app, event)
           % value = app.qn2Spinner.Value;
            execute(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-0.1 0.1];
            app.UIAxes.YLim = [-510 10];
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [10 500];
            app.Slider2.MajorTicks = [100 200 300 400 500];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.Value = 400;
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [10 90];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 50;
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.Limits = [10 500];
            app.Slider3.MajorTicks = [10 100 200 300 400 500];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            app.Slider3.Value = 400;
            app.wellwidthLabel = uilabel(app.UIFigure);
            app.wellwidthLabel.Text = '% well width';
            app.U1Label = uilabel(app.UIFigure);
            app.U1Label.Text = 'U1';
            app.U2Label = uilabel(app.UIFigure);
            app.U2Label.Text = 'U2';
            app.Panel = uipanel(app.UIFigure);
            app.qn1SpinnerLabel = uilabel(app.Panel);
            app.qn1SpinnerLabel.Text = 'qn1';
            app.qn1Spinner = uispinner(app.Panel);
            app.qn1Spinner.Limits = [1 10];
            app.qn1Spinner.ValueChangedFcn = createCallbackFcn(app, @qn1SpinnerValueChanged, true);
            app.qn1Spinner.Value = 1;
            app.qn2SpinnerLabel = uilabel(app.Panel);
            app.qn2SpinnerLabel.Text = 'qn2';
            app.qn2Spinner = uispinner(app.Panel);
            app.qn2Spinner.Limits = [1 10];
            app.qn2Spinner.ValueChangedFcn = createCallbackFcn(app, @qn2SpinnerValueChanged, true);
            app.qn2Spinner.Value = 1;
            app.PLOTSpinnerLabel = uilabel(app.UIFigure);
            app.PLOTSpinnerLabel.Text = 'PLOT';
            app.PLOTSpinner = uispinner(app.UIFigure);
            app.PLOTSpinner.Limits = [1 6];
            app.PLOTSpinner.ValueChangedFcn = createCallbackFcn(app, @PLOTSpinnerValueChanged, true);
            app.PLOTSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appQM001A

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end